classdef tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        FILE              matlab.ui.container.Menu
        READ              matlab.ui.container.Menu
        SAVE              matlab.ui.container.Menu
        CHANGE            matlab.ui.container.Menu
        EXIT              matlab.ui.container.Menu
        M_Con             matlab.ui.container.Menu
        MI_Run            matlab.ui.container.Menu
        Info              matlab.ui.container.Menu
        UPDATE            matlab.ui.container.Menu
        uipanel1          matlab.ui.container.ButtonGroup
        Mouse             matlab.ui.control.CheckBox
        T_Trl             matlab.ui.control.Label
        PB_Test           matlab.ui.control.Button
        text10            matlab.ui.control.Label
        juiceradio        matlab.ui.control.RadioButton
        Lbl_Rwtime        matlab.ui.control.Label
        text2             matlab.ui.control.Label
        ZoomDown          matlab.ui.control.Button
        ZoomUp            matlab.ui.control.Button
        text1             matlab.ui.control.Label
        Run               matlab.ui.control.Button
        Stop              matlab.ui.control.Button
        uipanel5          matlab.ui.container.ButtonGroup
        Transform         matlab.ui.container.ButtonGroup
        ROTATE            matlab.ui.control.RadioButton
        SCALE             matlab.ui.control.RadioButton
        SHIFT             matlab.ui.control.RadioButton
        lblScy            matlab.ui.control.Label
        lblScx            matlab.ui.control.Label
        lbl_Rot           matlab.ui.control.Label
        uipanel6          matlab.ui.container.Panel
        slider1           matlab.ui.control.Slider
        ParmPanel         matlab.ui.container.Panel
        rb_Drum           matlab.ui.control.CheckBox
        T_RUN             matlab.ui.control.Label
        e_RndTarg         matlab.ui.control.EditField
        txtranddtarg      matlab.ui.control.Label
        E_RGB             matlab.ui.control.EditField
        txrbg             matlab.ui.control.Label
        txteye            matlab.ui.control.Label
        TB_y              matlab.ui.control.StateButton
        TB_x              matlab.ui.control.StateButton
        e_Sacc            matlab.ui.control.EditField
        txtsactime        matlab.ui.control.Label
        e_Err             matlab.ui.control.EditField
        txterr            matlab.ui.control.Label
        e_RndStim         matlab.ui.control.EditField
        txtrandstim       matlab.ui.control.Label
        e_RndFix          matlab.ui.control.EditField
        txtrandfix        matlab.ui.control.Label
        txtcwh            matlab.ui.control.Label
        txtcww            matlab.ui.control.Label
        LTargHt           matlab.ui.control.EditField
        LFixHt            matlab.ui.control.EditField
        ReactionT         matlab.ui.control.EditField
        txtreact          matlab.ui.control.Label
        PixDeg            matlab.ui.control.Label
        ScrWid            matlab.ui.control.EditField
        ScrDist           matlab.ui.control.EditField
        txtscrwid         matlab.ui.control.Label
        txtscrdis         matlab.ui.control.Label
        txttilevisual     matlab.ui.control.Label
        LTargWd           matlab.ui.control.EditField
        LFixWd            matlab.ui.control.EditField
        txtlabelsdim      matlab.ui.control.Label
        txtwindimensions  matlab.ui.control.Label
        InterTT           matlab.ui.control.EditField
        txtintertrial     matlab.ui.control.Label
        TargT             matlab.ui.control.EditField
        txttarg           matlab.ui.control.Label
        StimT             matlab.ui.control.EditField
        txtstimon         matlab.ui.control.Label
        FixT              matlab.ui.control.EditField
        txtfixhold        matlab.ui.control.Label
        ToFixTime         matlab.ui.control.EditField
        txtfixtime        matlab.ui.control.Label
        txtTiming         matlab.ui.control.Label
        ParmClose         matlab.ui.control.Button
        axes_timing       matlab.ui.control.UIAxes
        axes1             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        RUNFUNC % Pointer to function
        DasOn % Description
    end
    
    properties (Access = private)
        TransformChoice % Description
    end
    
    methods (Access = private)
        function Init(app)
           
                 prestim %initial values, startup of das, startup of cogent
                 if isfield(Par,'DasOn') && Par.DasOn %defined in prestim
                     app.DasOn = true;
                 else
                     app.DasOn = false;
                 end
            
                 %axis(app.axes1, 'off')
                 app.axes1.XColor = 'w';
                 app.axes1.YColor = 'w';
                 axis(app.axes1, 'equal')
                 
            % initialize paramaterfields
              %timing
                app.ToFixTime.Value = num2str(Par.Times.ToFix, 4);
                app.FixT.Value = num2str(Par.Times.Fix, 4);
                app.StimT.Value = num2str(Par.Times.Stim, 4);
                app.TargT.Value = num2str(Par.Times.Targ, 4);
                app.ReactionT.Value = num2str(Par.Times.Rt, 4);
                app.InterTT.Value = num2str(Par.Times.InterTrial, 4);
            
                app.e_RndFix.Value = num2str(Par.Times.RndFix, 4);
                app.e_RndStim.Value = num2str(Par.Times.RndStim, 4);
                app.e_RndTarg.Value = num2str(Par.Times.RndTarg, 4);
                app.e_Sacc.Value = num2str(Par.Times.Sacc, 4);
                app.e_Err.Value = num2str(Par.Times.Err, 4);
            
                app.Lbl_Rwtime.Text = num2str(Par.RewardTime, 5);
                app.slider1.Value = Par.RewardTime;
            
                app.rb_Drum.Value = Par.Drum;
                app.E_RGB.Value = [ num2str(Par.BG(1)) ' ' num2str(Par.BG(2)) ' ' num2str(Par.BG(2))];
            
                Updatetimeaxes(app)
               %scale eyeinput to pixels
                app.lblScx.Text = num2str(Par.SCx, 4);
                app.lblScy.Text = num2str(Par.SCy, 4);
                app.TB_x.Text = ['x*' num2str(Par.xdir, 1)];
                app.TB_y.Text = ['y*' num2str(Par.ydir, 1)];
            
               %control window dimensions
                app.LFixWd.Value = num2str(Par.FixWdDeg);
                app.LTargWd.Value = num2str(Par.TargWdDeg);
                app.LFixHt.Value = num2str(Par.FixHtDeg);
                app.LTargHt.Value = num2str(Par.TargHtDeg);
            
                app.ScrDist.Value = num2str(Par.DistanceToScreen);
                app.ScrWid.Value = num2str(Par.ScreenWidthD2 * 2);
                app.PixDeg.Text = num2str(Par.PixPerDeg, 4);
            
                app.RUNFUNC = @runstim;
                app.T_RUN.Text = ['Runfunction : ' Par.RUNFUNC];
                
                app.TransformChoice = app.Transform.SelectedObject.Text;
        end
               
        function SAVEAS_Callback(app) %#ok<MANU> 
           
            global Par          
            [file,path] = uiputfile('PAR.mat','Save PAR as');
            if file ~= 0
                    save([path file], '-struct', 'Par')
            else
                errormsg('No filename supplied')
            end
        end
        
        function Updatetimeaxes(app)
            
                global Par
                
                axes(app.axes_timing);
                cla
                Times = Par.Times;
                
                plot([-(Times.ToFix + Times.Fix)  -Times.Fix], [0 0], 'Color', [0.75 0.75 0.75], 'LineWidth', 10)
                hold on
                plot([ -Times.Fix Times.Targ], [0 0], 'LineWidth', 10, 'Color', [0 0.75 0.75])
            
                plot([Times.Targ Times.Targ + Par.Times.Rt], [0 0], 'LineWidth', 10, 'Color', [0.75 0.75 0])
                plot([Times.Targ + Par.Times.Rt Times.Targ + Par.Times.Rt+Times.InterTrial], ...
                    [0 0], 'Color', [0.5 0.5 0.5], 'LineWidth', 10)
                plot([ 0 Times.Stim], [0 0], 'k', 'LineWidth', 5)
            
                line([-Times.Fix -Times.Fix], [-0.1 1])
                text(-Times.Fix, 0.2, 'Fix')
                line([0 0], [-0.1 1])
                text(0, 0.4, 'Stim')
                line([Times.Targ Times.Targ], [-0.1 1])
                text(Times.Targ, 0.6, 'Targ')
            
               % POS = get(handles.figure1, 'Position');
                lgnd1 = legend('Fix\_wait duration', 'Fixation time', 'Reaction time', 'Intertrial time', 'Stim\_on time');
                set(lgnd1,'Units','characters','Position', [5 14 30 8]);
                xlabel('time(ms)')
                set(app.axes_timing, 'YLim', [-0.1 1])
                set(app.axes_timing, 'YTick', [], 'YTickLabel', '')
            
                axes(app.axes1);
        end              
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tracker_OpeningFcn(app, varargin)
            
            Init(app)
        end

        % Callback function: CHANGE, uipanel5
        function CHANGE_Callback(app, event)
             set(app.axes1, 'Visible', 'off')
             CHILD = get(app.axes1, 'Children');
             for i = 1:length(CHILD)
                 set(CHILD(i), 'Visible', 'off')
             end
             set(app.ParmPanel, 'Visible', 'on')
             Updatetimeaxes(app) %update axes showing control times
        end

        % Menu selected function: EXIT
        function EXIT_Callback(app, event)

            global Par          
            if Par.isRunning == true     
                button = questdlg('Did your runstim crash','Alert','Yes');
                if strcmp(button, 'Yes')
                    Par.isRunning = false;
                    poststim
                    delete(app.figure1);
                end     
            else      
                delete(app.figure1);
                poststim       
            end
        end

        % Value changed function: E_RGB
        function E_RGB_Callback(app, event)
            global Par
                STR = app.E_RGB.Value;
                strexp = '\d\.\d|\d(?!\d)';
                RGBSTR = regexp(STR, strexp, 'match');
                RGB = zeros(3,1);
                if length(RGBSTR) == 3
                    for i = 1:3
                        RGB(i) = str2double(RGBSTR{i});
                        if RGB(i) > 1.0 || RGB(i) < 0.0
                            errordlg('Invalid input')
                            return
                        end
                    end
                    Par.BG = RGB;
                else
                    errordlg('Invalid input')
                end
        end

        % Value changed function: FixT
        function FixT_Callback(app, event)
            global Par
            time = str2double(app.FixT.Value);
            if isnan(time) || time == 0
                errordlg('Enter valid time')
            else
                Par.Times.Fix = time;
                Updatetimeaxes(app)
            end
        end

        % Value changed function: InterTT
        function InterTT_Callback(app, event)
            global Par
            time = str2double(app.InterTT.Value);
            if isnan(time) || time < 0
                errordlg('Enter valid time')
            else
                Par.Times.InterTrial = time;
                Updatetimeaxes(app)
            end
        end

        % Value changed function: LFixHt
        function LFixHt_Callback(app, event)
                global Par
                Val = str2double(app.LFixHt.Value); %returns contents of LFixSz as a double
                if ~isnan(Val) && Val > 0
                    Par.FixHtDeg = Val;
                    FixHt = Val * Par.PixPerDeg;
                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 0 %fix window == 0
                            Par.WIN(4,i) = FixHt; %width
                        end
                    end
                end
        end

        % Value changed function: LFixWd
        function LFixWd_Callback(app, event)
                global Par
                Val = str2double(app.LFixWd.Value); %returns contents of LFixSz as a double
                if ~isnan(Val) && Val > 0
                    Par.FixWdDeg = Val;
                    FixWd = Val * Par.PixPerDeg;
                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 0 %fix window == 0
                            Par.WIN(3,i) = FixWd; %width
                        end
                    end
                end
        end

        % Value changed function: LTargHt
        function LTargHt_Callback(app, event)
                global Par
                Val = str2double(app.LTargHt.Value); %returns contents of LFixSz as a double
                if ~isnan(Val) && Val > 0
                    Par.TargHtDeg = Val;
                    TargHt = Val * Par.PixPerDeg;
                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                            Par.WIN(4,i) = TargHt; %width
                        end
                    end
                end
        end

        % Value changed function: LTargWd
        function LTargWd_Callback(app, event)
                global Par
                Val = str2double(app.LTargWd.Value); %returns contents of LFixSz as a double
                if ~isnan(Val) && Val > 0
                    Par.TargWdDeg = Val;
                    TargWd = Val * Par.PixPerDeg;
                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                            Par.WIN(3,i) = TargWd; %width
                        end
                    end
                end
        end

        % Menu selected function: MI_Run
        function MI_Run_Callback(app, event)
                global Par
                FSTR = uigetfile('*.m');
                StrPth = which(FSTR);
                if strcmp(StrPth, '')
                    errordlg('This function is not in the matlab path')
                else
                    button =  questdlg({StrPth, '',  'Is this the function you want to use.?'},'Confirm');
                    if  strcmp(button, 'Yes')
                        Str = regexp(FSTR, '\w*', 'match');
                        Par.RUNFUNC = Str{1};
                        app.RUNFUNC = str2func(Par.RUNFUNC);
                        app.T_RUN.Text = Par.RUNFUNC;
                    end
                end
        end

        % Callback function
        function MI_Stim_Callback(app, event)
                  SG = Stimgui;
                  PS = get(SG, 'Position');
                  FPos = app.figure1.Position;
                 % SPos =  get(handles.figure1, 'Position');
                  PS = [FPos(1)+10  PS(2:4)];
                  set(SG, 'Position', PS);
        end

        % Value changed function: Mouse
        function Mouse_Callback(app, event)
            global Par
            val = app.Mouse.Value;
            if app.DasOn
                if(val >= 1)
                    Par.Mouserun = 1;
                    dasusemouse( 1 );
                else
                    Par.Mouserun = 0;
                    dasusemouse( 0 );
                end
            end
        end

        % Button pushed function: PB_Test
        function PB_Test_Callback(app, event)
            global Par
                if app.DasOn
                    set(app.PB_Test, 'BackgroundColor', [0.5 0.6 0.9])
                    Par.isRunning = true;
                    axes( app.axes1);
            
                    Par.ESC = false; %escape has not been pressed
                    Par.Updatxy = 1;
                    while ~Par.ESC
                        pause(0.05)
                        DasCheck; %retrieve position values and plot on Control display
                    end
                    Par.Updatxy = 0;
                end
                Par.isRunning = false;
                set(app.PB_Test, 'BackgroundColor', [0.925 0.914 0.847])
        end

        % Button pushed function: ParmClose
        function ParmClose_Callback(app, event)
              set(app.ParmPanel, 'Visible', 'off')          
              refreshtracker( 1)
              set(app.axes1, 'Visible', 'on')
        end

        % Menu selected function: READ
        function READ_Callback(app, event)
            global Par           
            RUNFUNC = ''; %#ok<ADPROPLC> 
            
            [FileName,PathName] = uigetfile('PAR.mat','Select a parameter Mat-file');
              Pf = fullfile(PathName,FileName);
            
            if exist(Pf, 'file')
                load(Pf)
                Par.Times = Times;
                Par.Drum = Drum;
            
                Par.SCx = SCx;
                Par.SCy = SCy;
            
                Par.DistanceToScreen = DistanceToScreen;
                Par.ScreenWidthD2 = ScreenWidthD2;
                Par.PixPerDeg = PixPerDeg;
                Par.FixWdDeg = FixWdDeg;
                Par.TargWdDeg = TargWdDeg;
                Par.FixHtDeg = FixHtDeg;
                Par.TargHtDeg = TargHtDeg;
                Par.xdir = xdir;
                Par.ydir = ydir;
            
                Par.BG = BG;
            
                if exist(RUNFUNC, 'var') %#ok<ADPROPLC> 
                    Par.RUNFUNC = RUNFUNC; %#ok<ADPROPLC> 
            
                    app.RUNFUNC = str2fun(Par.RUNFUNC);
                    app.T_RUN.text = ['Runfunction : ' Par.RUNFUNC];
                end
            
                app.ToFixTime.Value = num2str(Times.ToFix, 4);
                app.FixT.Value = num2str(Times.Fix, 4);
                app.StimT.Value = num2str(Times.Stim, 4);
                app.TargT.Value = num2str(Times.Targ, 4);
                app.ReactionT.Value = num2str(Par.Times.Rt, 4);
                app.InterTT.Value = num2str(Times.InterTrial, 4);
                Updatetimeaxes(app) %update axes showing control times
            
                app.e_RndFix.Value = num2str(Par.Times.RndFix, 4);
                app.e_RndStim.Value = num2str(Par.Times.RndStim, 4);
                app.e_RndTarg.Value = num2str(Par.Times.RndTarg, 4);
                app.e_Sacc.Value = num2str(Par.Times.Sacc, 4);
                app.e_Err.Value = num2str(Par.Times.Err, 4);
            
                app.rb_Drum.Value = Par.Drum;
                app.E_RGB.Value =  [ num2str(Par.BG(1)) ' ' num2str(Par.BG(2)) ' ' num2str(Par.BG(2))];
            
                app.lblScx.Text = num2str(Par.SCx, 4);
                app.lblScy.Text = num2str(Par.SCy, 4);
                app.TB_x.Text = ['x*' num2str(Par.xdir, 1)];
                app.TB_y.Text = ['y*' num2str(Par.ydir, 1)];
            
                   %control window dimensions
                app.LFixWd.Value = num2str(Par.FixWdDeg);
                app.LTargWd.Value = num2str(Par.TargWdDeg);
                app.LFixHt.Value = num2str(Par.FixHtDeg);
                app.LTargHt.Value = num2str(Par.TargHtDeg);
            
                app.ScrDist.Value = num2str(Par.DistanceToScreen);
                app.ScrWid.Value = num2str(Par.ScreenWidthD2 * 2);
                app.PixDeg.Value = num2str(Par.PixPerDeg, 4);


            %     FixSz = Par.FixSzDeg * Par.PixPerDeg;
            %     TargSz = Par.TargSzDeg * Par.PixPerDeg;
                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 0 %fix window == 0
                            Par.WIN(3,i) = Par.FixWdDeg * Par.PixPerDeg; %width
                            Par.WIN(4,i) = Par.FixHtDeg * Par.PixPerDeg; %height
            
                        elseif Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                            Par.WIN(3,i) = Par.TargWdDeg * Par.PixPerDeg; %width
                            Par.WIN(4,i) = Par.TargHtDeg * Par.PixPerDeg; %height
                        end
                    end
            else
                errordlg('No saved Parameters to retrieve')
            end
        end

        % Value changed function: ReactionT
        function ReactionT_Callback(app, event)
            global Par
            time = str2double(app.ReactionT.Value);
            if isnan(time) || time <= 0
                errordlg('Enter valid time')
            else
                Par.Times.Rt = time;
                Updatetimeaxes(app)
            end
        end

        % Button pushed function: Run
        function Run_Callback(app, event)
            global Par
            if app.DasOn
                app.Run.BackgroundColor = [0.5 0.6 0.9];
                Par.isRunning = true;
                axes(app.axes1);
            
                %the runfunction is now a handle, don't change!!!!!
                app.RUNFUNC([app.T_Trl app.lbl_Rot]) %gets a handle to update trial numbers and angle value
            
            end  %IS_RUNNING%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                Par.isRunning = false;
                app.Run.BackgroundColor = [0.925 0.914 0.847];
        end

        % Menu selected function: SAVE
        function SAVE_Callback(app, event)
            global Par          
            save( 'PAR.mat', '-struct', 'Par')
        end

        % Value changed function: ScrDist
        function ScrDist_Callback(app, event)
                global Par
                Val = str2double(app.ScrDist.Value); %returns contents as a double
                if ~isnan(Val) && Val > 0
                    Par.DistanceToScreen = Val;
                    Par.PixPerDeg = Par.HW/atand(Par.ScreenWidthD2/Val);
                    app.PixDeg.Value = num2str(Par.PixPerDeg);
                    
            %         TargSz = Par.TargSzDeg * Par.PixPerDeg;
            %         FixSz = Par.FixSzDeg * Par.PixPerDeg;
                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 0 %fix window == 0
                            Par.WIN(3,i) = Par.FixWdDeg * Par.PixPerDeg; %width
                             Par.WIN(4,i) = Par.FixHtDeg * Par.PixPerDeg; %height
            
                        elseif Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                            Par.WIN(3,i) = Par.TargWdDeg * Par.PixPerDeg; %width
                            Par.WIN(4,i) = Par.TargHtDeg * Par.PixPerDeg; %heigth
                        end
                    end
                else
                    errordlg('Invalid  input');
                end
        end

        % Value changed function: ScrWid
        function ScrWid_Callback(app, event)
            global Par            
                Val = str2double(app.ScrWid.Value); %returns contents as a double
                if ~isnan(Val) && Val > 0
                    Par.ScreenWidthD2 = Val/2;
                    Par.PixPerDeg = Par.HW/atand(Par.ScreenWidthD2/Par.DistanceToScreen);
                    app.PixDeg.Value = num2str(Par.PixPerDeg);

                    for i = 1:size(Par.WIN,2)
                        if Par.WIN(5,i) == 0 %fix window == 0
                            Par.WIN(3,i) = Par.FixWdDeg * Par.PixPerDeg; %width
                            Par.WIN(4,i) = Par.FixHtDeg * Par.PixPerDeg;%height
                        elseif Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                            Par.WIN(3,i) = Par.TargWdDeg * Par.PixPerDeg;  %width
                            Par.WIN(4,i) = Par.TargHtDeg * Par.PixPerDeg;
                        end
                    end
            
            
                else
                    errordlg('Invalid  input');
                end
        end

        % Value changed function: StimT
        function StimT_Callback(app, event)
            global Par
            time = str2double(app.StimT.Value);
            if isnan(time) || time <= 0
                errordlg('Enter valid time')
            else
                Par.Times.Stim = round(round(time/Par.fliptime)*Par.fliptime);
                app.StimT.Value = num2str(Par.Times.Stim);
                Updatetimeaxes(app)
            end
        end

        % Button pushed function: Stop
        function Stop_Callback(app, event)
            global Par            
            Par.ESC = true;
        end

        % Value changed function: TB_x
        function TB_x_Callback(app, event)
             global Par
                if Par.xdir == 1
                    Par.xdir = -1;
                else
                    Par.xdir = 1;
                end
                app.TB_x.Text = ['x*' num2str(Par.xdir, 1)];
        end

        % Value changed function: TB_y
        function TB_y_Callback(app, event)
             global Par
                if Par.ydir == 1
                    Par.ydir = -1;
                else
                    Par.ydir = 1;
                end
                app.TB_y.Text =  ['y*' num2str(Par.ydir, 1)];
        end

        % Value changed function: TargT
        function TargT_Callback(app, event)
            global Par
            time = str2double(app.TargT.Value);
            if isnan(time) || time < 0
                errordlg('Enter valid time')
            else
                Par.Times.Targ = round(round(time/Par.fliptime)*Par.fliptime);
                app.TargT.Value = num2str(Par.Times.Targ);
                Updatetimeaxes(app)
            end
        end

        % Value changed function: ToFixTime
        function ToFixTime_Callback(app, event)
            global Par
            time = str2double(app.ToFixTime.Value);
            if isnan(time) || time == 0
                errordlg('Enter valid time')
            else
                Par.Times.ToFix = time;
                Updatetimeaxes(app)
            end            
        end

        % Menu selected function: UPDATE
        function UPDATE_Callback(app, event)
                pad = which( 'Trackerupdates.html');
                web(pad)
        end

        % Button pushed function: ZoomDown
        function ZoomDown_Callback(app, event)
            global Par
            Par.ZOOM = Par.ZOOM * 0.8;
            cla
            refreshtracker(1)
        end

        % Button pushed function: ZoomUp
        function ZoomUp_Callback(app, event)
            global Par
            Par.ZOOM = Par.ZOOM * 1.25;
            cla
            refreshtracker(1)
        end

        % Value changed function: e_Err
        function e_Err_Callback(app, event)
                global Par
                Val = str2double(app.e_Err.Value);
                if ~isnan(Val)
                    Par.Times.Err = Val;
                else
                    errordlg('Invalid  input');
                end
        end

        % Value changed function: e_RndFix
        function e_RndFix_Callback(app, event)
                global Par
                Val = str2double(app.e_RndFix.Value);
                if ~isnan(Val)
                    Par.Times.RndFix = Val;
                else
                    errordlg('Invalid  input');
                end
        end

        % Value changed function: e_RndStim
        function e_RndStim_Callback(app, event)
                global Par
                Val = str2double(app.e_RndStim.Value);
                if ~isnan(Val) && Val >= 0
                    Par.Times.RndStim = Val;
                else
                    errordlg('Invalid  input');
                end
        end

        % Value changed function: e_RndTarg
        function e_RndTarg_Callback(app, event)
                global Par
                Val = str2double(app.e_RndTarg.Value);
                if ~isnan(Val) && Val >= 0
                    Par.Times.RndTarg = Val;
                else
                    errordlg('Invalid  input');
                end
        end

        % Value changed function: e_Sacc
        function e_Sacc_Callback(app, event)
                global Par
                Val = str2double(app.e_Sacc.Value);
                if ~isnan(Val)
                    Par.Times.Sacc = Val;
                else
                    errordlg('Invalid  input');
                end
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            global Par
            KP = event.Key;
            
            if ~isempty(KP)
             switch KP
            
                 case 'leftarrow'  %left arrow was pressed
            
                   if strcmp(app.TransformChoice,'ROTATE')
                        Par.Angle = Par.Angle + 1;
                        dasrotate(Par.Angle);
                        app.lbl_Rot.Text = num2str(Par.Angle);
                   elseif strcmp(app.TransformChoice, 'SHIFT')
                        dasoffset( -1, 0);
                   elseif strcmp(app.TransformChoice, 'SCALE')
                        Par.SCx = Par.SCx * 1/1.1;
                        dasscale(Par.SCx*Par.xdir, Par.SCy*Par.ydir);
                        app.lblScx.Text = num2str(Par.SCx, 4);
                   end        
                   
                case 'rightarrow'  %Right arrow was pressed
            
                   if strcmp(app.TransformChoice,'ROTATE')
                        Par.Angle = Par.Angle - 1;
                        dasrotate(Par.Angle);
                        app.lbl_Rot.Text = num2str(Par.Angle);
                    elseif strcmp(app.TransformChoice, 'SHIFT')
                        dasoffset( 1, 0);
                    elseif strcmp(app.TransformChoice, 'SCALE')
                        Par.SCx = Par.SCx * 1.1;
                        dasscale(Par.SCx*Par.xdir, Par.SCy*Par.ydir);
                        app.lblScx.Text = num2str(Par.SCx, 4);
                    end

            
                case 'uparrow'  %up arrow was pressed
            
                   if strcmp(app.TransformChoice, 'SHIFT')
                        dasoffset( 0, 1);
                   elseif strcmp(app.TransformChoice, 'SCALE')
                        Par.SCy = Par.SCy * 1.1;
                        dasscale(Par.SCx*Par.xdir, Par.SCy*Par.ydir);
                        app.lblScy.Text = num2str(Par.SCy, 4);
                   end

            
                case 'downarrow'  %down arrow was pressed
            
                    if strcmp(app.TransformChoice, 'SHIFT')
                        dasoffset( 0, -1);
                    elseif strcmp(app.TransformChoice, 'SCALE')
                        Par.SCy = Par.SCy * 1/1.1;
                        dasscale(Par.SCx*Par.xdir, Par.SCy*Par.ydir);
                        app.lblScy.Text = num2str(Par.SCy, 4);
                    end

            
                 case 'escape'  %escape key was pressed
                    Par.ESC = 1;
                   % disp('keypress ESC')
            
                case 'z' %Z key was pressed
                   Par.SetZero = true;
                   % disp('keypress ZERO')
                   if Par.SetZero
                       if Par.Mouserun
                           Par.MOff = dasusemouse( 1 );
                       else
                           %calllib(Par.Dll,'SetZero');
                           daszero();
                       end
                   end
            
                case 'j' %j key was pressed , give juice reward
                    if app.DasOn
                        dasbit( Par.RewardB, 1);
                        dasjuice( 6 );
                        pause(Par.RewardTime)
                        dasbit( Par.RewardB, 0);
                        dasjuice( 0 );
                        disp('Reward')
                    end
            
                otherwise
                    disp(KP)
            
             end
            
            end
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            global Par
            Par.RewardTime = app.slider1.Value;
            app.Lbl_Rwtime.Text = num2str(Par.RewardTime, '% -5.3f');
        end

        % Selection changed function: Transform
        function TransformSelectionChanged(app, event)
            app.TransformChoice = app.Transform.SelectedObject.Text;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tracker';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.FILE = uimenu(app.figure1);
            app.FILE.Accelerator = 'P';
            app.FILE.Text = 'File';
            app.FILE.Tag = 'FILE';
            app.READ = uimenu(app.FILE);
            app.READ.MenuSelectedFcn = createCallbackFcn(app, @READ_Callback, true);
            app.READ.Text = 'Read Parameters';
            app.READ.Tag = 'READ';
            app.SAVE = uimenu(app.FILE);
            app.SAVE.MenuSelectedFcn = createCallbackFcn(app, @SAVE_Callback, true);
            app.SAVE.Text = 'Save Parameters';
            app.SAVE.Tag = 'SAVE';
            app.CHANGE = uimenu(app.FILE);
            app.CHANGE.MenuSelectedFcn = createCallbackFcn(app, @CHANGE_Callback, true);
            app.CHANGE.Text = 'Change Parameters';
            app.CHANGE.Tag = 'CHANGE';
            app.EXIT = uimenu(app.FILE);
            app.EXIT.MenuSelectedFcn = createCallbackFcn(app, @EXIT_Callback, true);
            app.EXIT.Text = 'Exit';
            app.EXIT.Tag = 'EXIT';
            app.M_Con = uimenu(app.figure1);
            app.M_Con.Text = 'Context';
            app.M_Con.Tag = 'M_Con';
            app.MI_Run = uimenu(app.M_Con);
            app.MI_Run.MenuSelectedFcn = createCallbackFcn(app, @MI_Run_Callback, true);
            app.MI_Run.Text = 'NewRunfunction';
            app.MI_Run.Tag = 'MI_Run';
            app.Info = uimenu(app.figure1);
            app.Info.Text = 'Info';
            app.Info.Tag = 'Info';
            app.UPDATE = uimenu(app.Info);
            app.UPDATE.MenuSelectedFcn = createCallbackFcn(app, @UPDATE_Callback, true);
            app.UPDATE.Checked = 'on';
            app.UPDATE.Text = 'Updates';
            app.UPDATE.Tag = 'UPDATE';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.ParmPanel = uipanel(app.figure1);
            app.ParmPanel.Title = 'Parameters';
            app.ParmPanel.Visible = 'off';
            app.ParmPanel.Tag = 'ParmPanel';
            app.axes_timing = uiaxes(app.ParmPanel);
            app.axes_timing.YTick = zeros(1,0);
            app.axes_timing.YTickLabel = {''; ''; ''};
            app.axes_timing.Tag = 'axes_timing';
            app.ParmClose = uibutton(app.ParmPanel, 'push');
            app.ParmClose.ButtonPushedFcn = createCallbackFcn(app, @ParmClose_Callback, true);
            app.ParmClose.Tag = 'ParmClose';
            app.ParmClose.Text = 'CLOSE';
            app.txtTiming = uilabel(app.ParmPanel);
            app.txtTiming.Tag = 'text4';
            app.txtTiming.Text = 'Timing (ms)';
            app.txtfixtime = uilabel(app.ParmPanel);
            app.txtfixtime.Tag = 'text5';
            app.txtfixtime.Text = 'Time to fix';
            app.ToFixTime = uieditfield(app.ParmPanel, 'text');
            app.ToFixTime.ValueChangedFcn = createCallbackFcn(app, @ToFixTime_Callback, true);
            app.ToFixTime.Tag = 'ToFixTime';
            app.ToFixTime.Value = '0';
            app.txtfixhold = uilabel(app.ParmPanel);
            app.txtfixhold.Tag = 'text6';
            app.txtfixhold.Text = 'Fix hold';
            app.FixT = uieditfield(app.ParmPanel, 'text');
            app.FixT.ValueChangedFcn = createCallbackFcn(app, @FixT_Callback, true);
            app.FixT.Tag = 'FixT';
            app.FixT.Value = '0';
            app.txtstimon = uilabel(app.ParmPanel);
            app.txtstimon.Tag = 'text7';
            app.txtstimon.Text = 'Stim on time';
            app.StimT = uieditfield(app.ParmPanel, 'text');
            app.StimT.ValueChangedFcn = createCallbackFcn(app, @StimT_Callback, true);
            app.StimT.Tag = 'StimT';
            app.StimT.Value = '0';
            app.txttarg = uilabel(app.ParmPanel);
            app.txttarg.Tag = 'text8';
            app.txttarg.Text = 'Target time';
            app.TargT = uieditfield(app.ParmPanel, 'text');
            app.TargT.ValueChangedFcn = createCallbackFcn(app, @TargT_Callback, true);
            app.TargT.Tag = 'TargT';
            app.TargT.Value = '0';
            app.txtintertrial = uilabel(app.ParmPanel);
            app.txtintertrial.Tag = 'text9';
            app.txtintertrial.Text = 'Intertrial';
            app.InterTT = uieditfield(app.ParmPanel, 'text');
            app.InterTT.ValueChangedFcn = createCallbackFcn(app, @InterTT_Callback, true);
            app.InterTT.Tag = 'InterTT';
            app.InterTT.Value = '0';
            app.txtwindimensions = uilabel(app.ParmPanel);
            app.txtwindimensions.Tag = 'text14';
            app.txtwindimensions.Text = 'Control window dimensions (deg)';
            app.txtlabelsdim = uilabel(app.ParmPanel);
            app.txtlabelsdim.Tag = 'text15';
            app.txtlabelsdim.Text = 'Fix        Target  Scale(pix/deg)';
            app.LFixWd = uieditfield(app.ParmPanel, 'text');
            app.LFixWd.ValueChangedFcn = createCallbackFcn(app, @LFixWd_Callback, true);
            app.LFixWd.Tag = 'LFixWd';
            app.LFixWd.Value = '0';
            app.LTargWd = uieditfield(app.ParmPanel, 'text');
            app.LTargWd.ValueChangedFcn = createCallbackFcn(app, @LTargWd_Callback, true);
            app.LTargWd.Tag = 'LTargWd';
            app.LTargWd.Value = '0';
            app.txttilevisual = uilabel(app.ParmPanel);
            app.txttilevisual.Tag = 'text18';
            app.txttilevisual.Text = 'Visual scaling';
            app.txtscrdis = uilabel(app.ParmPanel);
            app.txtscrdis.Tag = 'text19';
            app.txtscrdis.Text = 'Distance to screen (cm)';
            app.txtscrwid = uilabel(app.ParmPanel);
            app.txtscrwid.Tag = 'text20';
            app.txtscrwid.Text = 'Screen width (cm)';
            app.ScrDist = uieditfield(app.ParmPanel, 'text');
            app.ScrDist.ValueChangedFcn = createCallbackFcn(app, @ScrDist_Callback, true);
            app.ScrDist.Tag = 'ScrDist';
            app.ScrDist.Value = '0';
            app.ScrWid = uieditfield(app.ParmPanel, 'text');
            app.ScrWid.ValueChangedFcn = createCallbackFcn(app, @ScrWid_Callback, true);
            app.ScrWid.Tag = 'ScrWid';
            app.ScrWid.Value = '0';
            app.PixDeg = uilabel(app.ParmPanel);
            app.PixDeg.Tag = 'PixDeg';
            app.PixDeg.Text = '0';
            app.txtreact = uilabel(app.ParmPanel);
            app.txtreact.Tag = 'text21';
            app.txtreact.Text = 'Reaction time';
            app.ReactionT = uieditfield(app.ParmPanel, 'text');
            app.ReactionT.ValueChangedFcn = createCallbackFcn(app, @ReactionT_Callback, true);
            app.ReactionT.Tag = 'ReactionT';
            app.ReactionT.Value = '0';
            app.LFixHt = uieditfield(app.ParmPanel, 'text');
            app.LFixHt.ValueChangedFcn = createCallbackFcn(app, @LFixHt_Callback, true);
            app.LFixHt.Tag = 'LFixHt';
            app.LFixHt.Value = '0';
            app.LTargHt = uieditfield(app.ParmPanel, 'text');
            app.LTargHt.ValueChangedFcn = createCallbackFcn(app, @LTargHt_Callback, true);
            app.LTargHt.Tag = 'LTargHt';
            app.LTargHt.Value = '0';
            app.txtcww = uilabel(app.ParmPanel);
            app.txtcww.Tag = 'text23';
            app.txtcww.Text = 'Wide';
            app.txtcwh = uilabel(app.ParmPanel);
            app.txtcwh.Tag = 'text24';
            app.txtcwh.Text = 'High';
            app.txtrandfix = uilabel(app.ParmPanel);
            app.txtrandfix.Tag = 'text25';
            app.txtrandfix.Text = 'Random fix';
            app.e_RndFix = uieditfield(app.ParmPanel, 'text');
            app.e_RndFix.ValueChangedFcn = createCallbackFcn(app, @e_RndFix_Callback, true);
            app.e_RndFix.Tag = 'e_RndFix';
            app.e_RndFix.Value = '0';
            app.txtrandstim = uilabel(app.ParmPanel);
            app.txtrandstim.Tag = 'text26';
            app.txtrandstim.Text = 'Random stim';
            app.e_RndStim = uieditfield(app.ParmPanel, 'text');
            app.e_RndStim.ValueChangedFcn = createCallbackFcn(app, @e_RndStim_Callback, true);
            app.e_RndStim.Tag = 'e_RndStim';
            app.e_RndStim.Value = '0';
            app.txterr = uilabel(app.ParmPanel);
            app.txterr.Tag = 'text27';
            app.txterr.Text = 'Add on error';
            app.e_Err = uieditfield(app.ParmPanel, 'text');
            app.e_Err.ValueChangedFcn = createCallbackFcn(app, @e_Err_Callback, true);
            app.e_Err.Tag = 'e_Err';
            app.e_Err.Value = '0';
            app.txtsactime = uilabel(app.ParmPanel);
            app.txtsactime.Tag = 'text28';
            app.txtsactime.Text = 'Saccade time';
            app.e_Sacc = uieditfield(app.ParmPanel, 'text');
            app.e_Sacc.ValueChangedFcn = createCallbackFcn(app, @e_Sacc_Callback, true);
            app.e_Sacc.Tag = 'e_Sacc';
            app.e_Sacc.Value = '0';
            app.TB_x = uibutton(app.ParmPanel, 'state');
            app.TB_x.ValueChangedFcn = createCallbackFcn(app, @TB_x_Callback, true);
            app.TB_x.Tag = 'TB_x';
            app.TB_x.Text = 'x*1';
            app.TB_y = uibutton(app.ParmPanel, 'state');
            app.TB_y.ValueChangedFcn = createCallbackFcn(app, @TB_y_Callback, true);
            app.TB_y.Tag = 'TB_y';
            app.TB_y.Text = 'y*1';
            app.txteye = uilabel(app.ParmPanel);
            app.txteye.Tag = 'text31';
            app.txteye.Text = 'Eye';
            app.txrbg = uilabel(app.ParmPanel);
            app.txrbg.Tag = 'text32';
            app.txrbg.Text = 'Background color';
            app.E_RGB = uieditfield(app.ParmPanel, 'text');
            app.E_RGB.ValueChangedFcn = createCallbackFcn(app, @E_RGB_Callback, true);
            app.E_RGB.Tag = 'E_RGB';
            app.E_RGB.Tooltip = 'enter RGB value 0-1 0-1 0-1';
            app.E_RGB.Value = '0 0 0';
            app.txtranddtarg = uilabel(app.ParmPanel);
            app.txtranddtarg.Tag = 'text37';
            app.txtranddtarg.Text = 'Random targ';
            app.e_RndTarg = uieditfield(app.ParmPanel, 'text');
            app.e_RndTarg.ValueChangedFcn = createCallbackFcn(app, @e_RndTarg_Callback, true);
            app.e_RndTarg.Tag = 'e_RndTarg';
            app.e_RndTarg.Value = '0';
            app.T_RUN = uilabel(app.ParmPanel);
            app.T_RUN.Tag = 'T_RUN';
            app.T_RUN.Text = 'Runfunction ';
            app.rb_Drum = uicheckbox(app.ParmPanel);
            app.rb_Drum.Tag = 'rb_Drum';
            app.rb_Drum.Text = 'Drumming on/off';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Control';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel6 = uipanel(app.uipanel1);
            app.uipanel6.Title = 'REWARD';
            app.uipanel6.Tag = 'uipanel6';
            app.slider1 = uislider(app.uipanel6);
            app.slider1.Limits = [0.05 1];
            app.slider1.MajorTicks = [];
            app.slider1.MajorTickLabels = {''};
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider1.Value = 0.1;
            app.uipanel5 = uibuttongroup(app.uipanel1);
            app.uipanel5.SelectionChangedFcn = createCallbackFcn(app, @CHANGE_Callback, true);
            app.uipanel5.Title = 'EYE CHANNELS';
            app.uipanel5.Tag = 'uipanel5';
            app.lbl_Rot = uilabel(app.uipanel5);
            app.lbl_Rot.Tag = 'lbl_Rot';
            app.lbl_Rot.Text = '0';
            app.lblScx = uilabel(app.uipanel5);
            app.lblScx.Tag = 'lblScx';
            app.lblScx.Text = '0';
            app.lblScy = uilabel(app.uipanel5);
            app.lblScy.Tag = 'lblScy';
            app.lblScy.Text = '0';
            app.Transform = uibuttongroup(app.uipanel5);
            app.Transform.SelectionChangedFcn = createCallbackFcn(app, @TransformSelectionChanged, true);
            app.SHIFT = uiradiobutton(app.Transform);
            app.SHIFT.Text = 'SHIFT';
            app.SHIFT.Value = true;
            app.SCALE = uiradiobutton(app.Transform);
            app.SCALE.Text = 'SCALE';
            app.ROTATE = uiradiobutton(app.Transform);
            app.ROTATE.Text = 'ROTATE';
            app.Stop = uibutton(app.uipanel1, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @Stop_Callback, true);
            app.Stop.Tag = 'Stop';
            app.Stop.Text = 'Stop';
            app.Run = uibutton(app.uipanel1, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @Run_Callback, true);
            app.Run.Tag = 'Run';
            app.Run.Text = 'Run';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'ZOOM';
            app.ZoomUp = uibutton(app.uipanel1, 'push');
            app.ZoomUp.ButtonPushedFcn = createCallbackFcn(app, @ZoomUp_Callback, true);
            app.ZoomUp.Tag = 'ZoomUp';
            app.ZoomUp.Text = 'In';
            app.ZoomDown = uibutton(app.uipanel1, 'push');
            app.ZoomDown.ButtonPushedFcn = createCallbackFcn(app, @ZoomDown_Callback, true);
            app.ZoomDown.Tag = 'ZoomDown';
            app.ZoomDown.Text = 'Out';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'juice_on time'; ''};
            app.Lbl_Rwtime = uilabel(app.uipanel1);
            app.Lbl_Rwtime.Tag = 'Lbl_Rwtime';
            app.Lbl_Rwtime.Text = '0.1';
            app.juiceradio = uiradiobutton(app.uipanel1);
            app.juiceradio.Tag = 'juiceradio';
            app.juiceradio.Text = 'ON/OFF';
            app.juiceradio.Value = true;
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = {'x'; 'y'; 'angle'};
            app.PB_Test = uibutton(app.uipanel1, 'push');
            app.PB_Test.ButtonPushedFcn = createCallbackFcn(app, @PB_Test_Callback, true);
            app.PB_Test.Tag = 'PB_Test';
            app.PB_Test.Text = 'Test Eye';
            app.T_Trl = uilabel(app.uipanel1);
            app.T_Trl.Tag = 'T_Trl';
            app.T_Trl.Text = {'TRIALS'; 'N: 0  '; 'C: 0'; 'E: 0'};
            app.Mouse = uicheckbox(app.uipanel1);
            app.Mouse.ValueChangedFcn = createCallbackFcn(app, @Mouse_Callback, true);
            app.Mouse.Tag = 'Mouse';
            app.Mouse.Text = 'Mouse';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tracker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tracker_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end